classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        RunButton                       matlab.ui.control.Button
        Label_2                         matlab.ui.control.Label
        MatrixParametersPanel           matlab.ui.container.Panel
        ConstraintLengthEditFieldLabel  matlab.ui.control.Label
        ConstraintLengthEditField       matlab.ui.control.EditField
        CoefficientsEditFieldLabel      matlab.ui.control.Label
        CoefficientsEditField           matlab.ui.control.EditField
        TypePanel                       matlab.ui.container.Panel
        WithoutCodingCheckBox           matlab.ui.control.CheckBox
        RepetitionCheckBox              matlab.ui.control.CheckBox
        HammingCheckBox                 matlab.ui.control.CheckBox
        ConvolutionCheckBox             matlab.ui.control.CheckBox
        NumericalParametersPanel        matlab.ui.container.Panel
        kEditFieldLabel                 matlab.ui.control.Label
        kEditField                      matlab.ui.control.NumericEditField
        SNRminEditFieldLabel            matlab.ui.control.Label
        SNRminEditField                 matlab.ui.control.NumericEditField
        SNRmaxEditFieldLabel            matlab.ui.control.Label
        SNRmaxEditField                 matlab.ui.control.NumericEditField
        nEditFieldLabel                 matlab.ui.control.Label
        nEditField                      matlab.ui.control.NumericEditField
        mEditFieldLabel                 matlab.ui.control.Label
        mEditField                      matlab.ui.control.NumericEditField
        CreatorsinAlphabeticalOrderGhafooriNourbakhshOnsoriLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: WithoutCodingCheckBox
        function WithoutCodingCheckBoxValueChanged(app, event)

        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            cla(app.UIAxes);
            app.Label_2.Text='WithoutCoding : Green  , Repetition : Black , Hamming : Red , Convolution : Blue';
            [SNR_without,BER_without]=Repeat(1,app.SNRminEditField.Value,app.SNRmaxEditField.Value);
            [SNR_HAM,BER_HAM]=Hamming(app.nEditField.Value,app.kEditField.Value,app.SNRminEditField.Value,app.SNRmaxEditField.Value); 
            [SNR_REP,BER_REP]=Repeat(app.mEditField.Value,app.SNRminEditField.Value,app.SNRmaxEditField.Value);
            [SNR_CNV,BER_CNV]=Convolutional(app.SNRminEditField.Value,app.SNRmaxEditField.Value,str2num(app.ConstraintLengthEditField.Value),str2num(app.CoefficientsEditField.Value));
            W=app.WithoutCodingCheckBox.Value;
            H=app.HammingCheckBox.Value;
            R=app.RepetitionCheckBox.Value;
            C=app.ConvolutionCheckBox.Value;
            hold(app.UIAxes,'on');
            if(W==1)
                plot(app.UIAxes,SNR_without,BER_without,'Color','g');
            end
            if(R==1)
                plot(app.UIAxes,SNR_REP,BER_REP,'Color','k');  

            end
            if(H==1)
                plot(app.UIAxes,SNR_HAM,BER_HAM,'Color','r'); 
            end
            if(C==1)
                plot(app.UIAxes,SNR_CNV,BER_CNV,'Color','b');  
            end
            hold(app.UIAxes,'off');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'BER-SNR')
            xlabel(app.UIAxes, 'SNR')
            ylabel(app.UIAxes, 'BER')
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.MatrixParametersPanel = uipanel(app.UIFigure);
            app.MatrixParametersPanel.Title = 'Matrix Parameters';
            app.ConstraintLengthEditFieldLabel = uilabel(app.MatrixParametersPanel);
            app.ConstraintLengthEditFieldLabel.Text = 'ConstraintLength';
            app.ConstraintLengthEditField = uieditfield(app.MatrixParametersPanel, 'text');
            app.ConstraintLengthEditField.Value = '[5 4]';
            app.CoefficientsEditFieldLabel = uilabel(app.MatrixParametersPanel);
            app.CoefficientsEditFieldLabel.Text = 'Coefficients';
            app.CoefficientsEditField = uieditfield(app.MatrixParametersPanel, 'text');
            app.CoefficientsEditField.Value = '[23 35 0;0 5 13]';
            app.TypePanel = uipanel(app.UIFigure);
            app.TypePanel.Title = 'Type';
            app.WithoutCodingCheckBox = uicheckbox(app.TypePanel);
            app.WithoutCodingCheckBox.ValueChangedFcn = createCallbackFcn(app, @WithoutCodingCheckBoxValueChanged, true);
            app.WithoutCodingCheckBox.Text = 'Without Coding';
            app.RepetitionCheckBox = uicheckbox(app.TypePanel);
            app.RepetitionCheckBox.Text = 'Repetition';
            app.HammingCheckBox = uicheckbox(app.TypePanel);
            app.HammingCheckBox.Text = 'Hamming';
            app.ConvolutionCheckBox = uicheckbox(app.TypePanel);
            app.ConvolutionCheckBox.Text = 'Convolution';
            app.NumericalParametersPanel = uipanel(app.UIFigure);
            app.NumericalParametersPanel.Title = 'Numerical Parameters';
            app.kEditFieldLabel = uilabel(app.NumericalParametersPanel);
            app.kEditFieldLabel.Text = 'k';
            app.kEditField = uieditfield(app.NumericalParametersPanel, 'numeric');
            app.SNRminEditFieldLabel = uilabel(app.NumericalParametersPanel);
            app.SNRminEditFieldLabel.Text = 'SNRmin';
            app.SNRminEditField = uieditfield(app.NumericalParametersPanel, 'numeric');
            app.SNRmaxEditFieldLabel = uilabel(app.NumericalParametersPanel);
            app.SNRmaxEditFieldLabel.Text = 'SNRmax';
            app.SNRmaxEditField = uieditfield(app.NumericalParametersPanel, 'numeric');
            app.nEditFieldLabel = uilabel(app.NumericalParametersPanel);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.NumericalParametersPanel, 'numeric');
            app.mEditFieldLabel = uilabel(app.NumericalParametersPanel);
            app.mEditFieldLabel.Text = 'm';
            app.mEditField = uieditfield(app.NumericalParametersPanel, 'numeric');
            app.CreatorsinAlphabeticalOrderGhafooriNourbakhshOnsoriLabel = uilabel(app.UIFigure);
            app.CreatorsinAlphabeticalOrderGhafooriNourbakhshOnsoriLabel.Text = '''Creators in Alphabetical Order : Ghafoori-Nourbakhsh-Onsori''';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end